classdef HistoLens_Extract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StructureNamesUIFigure         matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        ExperimentFileNameEditField    matlab.ui.control.EditField
        ExperimentFileNameEditFieldLabel  matlab.ui.control.Label
        CombinewithotherstructureDropDown  matlab.ui.control.DropDown
        CombinewithotherstructureDropDownLabel  matlab.ui.control.Label
        IgnorethisStructureButton      matlab.ui.control.Button
        StructureNameEditField         matlab.ui.control.EditField
        StructureNameEditFieldLabel    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        AvailableAnnotationIDsListBox  matlab.ui.control.ListBox
        AvailableAnnotationIDsListBoxLabel  matlab.ui.control.Label
        ExtractingStructurefromWSILabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        main_app
        Slide_Path
        
        HistoLens_CompartmentSegmentation
        Structure_Names
        MPP
        Experiment_File
        Annotation_Format

    end
    
    methods (Access = public)
        
        function new_var_name = Sanitize_UserInput(app,old_var_name)
            if isvarname(old_var_name)
                new_var_name = old_var_name;
            else
                if isstring(old_var_name)
                    replace_name = char(old_var_name);
                else
                    replace_name = old_var_name;
                end
                replace_name(find(~isletter(replace_name))) = [];

                if ~isempty(replace_name)
                    if isvarname(replace_name)
                        if isstring(old_var_name)
                            new_var_name = string(replace_name);
                        else
                            new_var_name = replace_name;
                        end
                    else
                        new_var_name = [];
                    end
                else
                    new_var_name = [];
                end
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Path = main_app.Slide_Path;
            
            % Getting only the xml files in the slide path
            dir_contents = dir(app.Slide_Path);
            dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
            dir_contents = dir_contents(contains({dir_contents.name},{'.xml'}));
            dir_contents = {dir_contents.name};
            
            % Adding json and geojson compatibility
            if isempty(dir_contents)
                app.Annotation_Format = 'JSON';

                dir_contents = dir(app.Slide_Path);
                dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
                dir_contents = dir_contents(contains({dir_contents.name},{'.json','.geojson'}));
                dir_contents = {dir_contents.name};
            else
                app.Annotation_Format = 'XML';
            end

            % Initializing list of labeled structures
            if strcmp(app.Annotation_Format,'XML')
                annotation_IDs = zeros(length(dir_contents),1);
                for i = 1:length(dir_contents)
                    read_xml = xmlread(strcat(app.Slide_Path,filesep,dir_contents{i}));
                    annotations = read_xml.getElementsByTagName('Annotation');
                    
                    annotation_IDs(i) = annotations.getLength;
                    for s = 1:annotations.getLength
                        current_layer = annotations.item(s-1);
    
                        % Checking if this is a named layer, only need one
                        % slide to have labels in each batch    
                        if current_layer.hasAttribute('Name') && string(current_layer.getAttribute('Name')) ~= "" && string(current_layer.getAttribute('Name')) ~= strcat("Layer ",num2str(s))
                            
                            % Sanitizing user input during ingestion
                            % ("1.Glomeruli" is not a valid field name)
                            if ~isvarname(string(current_layer.getAttribute('Name')))
                                layer_name = Sanitize_UserInput(app,string(current_layer.getAttribute('Name')));
                                layer_name = strcat(layer_name,'_',num2str(s));
                            else
                                layer_name = strcat(string(current_layer.getAttribute('Name')),'_',num2str(s));
                            end

                            app.Structure_Names.(strcat('Layer_',num2str(s)))=layer_name;

                        elseif isempty(app.Structure_Names)
                            app.Structure_Names.(strcat('Layer_',num2str(s)))={strcat('Structure_',num2str(s))};

                        elseif ~ismember(strcat('Layer_',num2str(s)),fieldnames(app.Structure_Names))
                            app.Structure_Names.(strcat('Layer_',num2str(s))) = {strcat('Structure_',num2str(s))};

                        %else
                        %    app.Structure_Names.(strcat('Layer_',num2str(s))) = {strcat('Structure_',num2str(s))};
                        end
                    end
                end
            else
                % Placeholder for JSON annotations
                msgbox('Working on it!')

            end


            try
                app.MPP = read_xml.getElementsByTagName('Annotations');
                app.MPP = str2double(app.MPP.item(0).getAttribute('MicronsPerPixel'));
            end
            
            % Total number of available annotation IDs included in slides
            total_annotations = length(fieldnames(app.Structure_Names));
            % Populating list box with initial annotation labels
            for j = 1:total_annotations
                if j == 1
                    structure_names = app.Structure_Names.(strcat('Layer_',num2str(j)));
                else
                    structure_names = [structure_names;app.Structure_Names.(strcat('Layer_',num2str(j)))];
                end
            end
            
            display(strjoin([{'Found: '}, structure_names']))

            app.AvailableAnnotationIDsListBox.Items = structure_names;
            app.CombinewithotherstructureDropDown.Items = structure_names;

            % Populating Experiment File Name field
            default_experiment_name = strsplit(app.Slide_Path,filesep);
            default_experiment_name = default_experiment_name{end};

            % Testing for if the folder name is a number (and changing
            % default experiment name)
            if isnumeric(default_experiment_name)
                default_experiment_name = strcat(num2str(default_experiment_name),'_HistoLens_Experiment');
            end

            if ~isempty(str2num(default_experiment_name))
                default_experiment_name = strcat(default_experiment_name,'_HistoLens_Experiment');
            end
            
            app.Experiment_File = default_experiment_name;
            app.ExperimentFileNameEditField.Value = default_experiment_name;


        end

        % Value changed function: AvailableAnnotationIDsListBox
        function AvailableAnnotationIDsListBoxValueChanged(app, event)
            value = app.AvailableAnnotationIDsListBox.Value;
            
            if length(value)==1
                % Splitting the name of the structure and the annotation ID
                % number by an underscore
                value = strsplit(value{1},'_');
                value = value{1};
            else
                values = cellfun(@(x) strsplit(x,'_'), value,'UniformOutput',false);
                values = cellfun(@(x) x{1}, values, 'UniformOutput',false);
                value = strjoin(values,',');
            end
            
            app.StructureNameEditField.Value = value;
            
        end

        % Value changed function: StructureNameEditField
        function StructureNameEditFieldValueChanged(app, event)
            value = app.StructureNameEditField.Value;
            
            san_name = Sanitize_UserInput(app,value);

            if ~strcmp(san_name,value) && ~isempty(san_name)
                msgbox(strcat(['Your provided structure name:',value,' was invalid.\n',...
                    '(Contained either a special character, numbers, or spaces)\n',...
                    'Check if the replacement is satisfactory.']));
            end
            if ~isempty(san_name)
                value = san_name;
            
                % Getting previous annotation ID values
                old_value = app.AvailableAnnotationIDsListBox.Value;
                if length(old_value)==1
                    prev_IDs = strsplit(old_value{1},'_');
                    prev_IDs = prev_IDs{end};
                    
                    new_value = strcat(value,'_',prev_IDs);
                    structure_idx = find(strcmp(app.AvailableAnnotationIDsListBox.Items,old_value));
                    app.AvailableAnnotationIDsListBox.Items(structure_idx) = {new_value};
                    
                    % Updating name in combine with other structures drop down
                    structure_idx = find(strcmp(app.CombinewithotherstructureDropDown.Items,old_value));
                    app.CombinewithotherstructureDropDown.Items(structure_idx) = {new_value};
                else
                    prev_IDs = cellfun(@(x) strsplit(x,'_'),old_value,'UniformOutput',false);
                    prev_IDs = cellfun(@(x) x{end},prev_IDs,'UniformOutput',false);
                    prev_IDs = strjoin(prev_IDs,',');
    
                    new_value = strcat(value,'_',prev_IDs);
    
                    remove_idxes = find(ismember(old_value,app.AvailableAnnotationIDsListBox.Items));
                    structure_idx = find(strcmp(app.AvailableAnnotationIDsListBox.Items,old_value{1}));
                    
                    app.AvailableAnnotationIDsListBox.Items(remove_idxes) = [];
                    app.AvailableAnnotationIDsListBox.Items(structure_idx) = {new_value};
    
                    % Updating name in combine with other structures drop down
                    old_idx = find(ismember(old_value,app.CombinewithotherstructureDropDown.Items));
                    app.CombinewithotherstructureDropDown.Items(old_idx) = {value};
                    app.CombinewithotherstructureDropDown.Items = unique(app.CombinewithotherstructureDropDown.Items);
                end
            end
            app.StructureNameEditField.Value = '';
            
        end

        % Button pushed function: IgnorethisStructureButton
        function IgnorethisStructureButtonPushed(app, event)
            
            % Updating list box structure names
            structure_idx = find(strcmp(app.AvailableAnnotationIDsListBox.Items,app.AvailableAnnotationIDsListBox.Value));
            app.AvailableAnnotationIDsListBox.Items(structure_idx) = [];
            
            % Updating combine with other structure drop down
            structure_idx = find(strcmp(app.CombinewithotherstructureDropDown.Items,app.AvailableAnnotationIDsListBox.Value));
            app.CombinewithotherstructureDropDown.Items(structure_idx) = [];
            
            
        end

        % Value changed function: CombinewithotherstructureDropDown
        function CombinewithotherstructureDropDownValueChanged(app, event)
            value = app.CombinewithotherstructureDropDown.Value;
            
            % Get current structure name 
            current_structure = app.AvailableAnnotationIDsListBox.Value;
            
            if ~strcmp(value,current_structure)
                % Getting previous IDs for structure that will be combined with
                % another 
                prev_IDs = strsplit(current_structure,'_');
                old_name = prev_IDs{1};
                prev_IDs = prev_IDs{end};
                
                % Getting IDs of structure that is being combined with
                comb_ids = strsplit(value,'_');
                comb_ids = comb_ids{end};
                
                % New ids
                new_ids = strjoin([prev_IDs,comb_ids],',');
                
                app.AvailableAnnotationIDsListBox.Value = strcat(old_name,'_',new_ids);
            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            if ~isempty(app.AvailableAnnotationIDsListBox.Items) && ~isempty(app.ExperimentFileNameEditField.Value)
                % Collect and sort the structure names and the annotation IDs
                % associated with that structure
                Get_Structure_Names(app.main_app,app)
                delete(app)
            elseif isempty(app.AvailableAnnotationIDsListBox.Items) && isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No annotation ids or experiment file name!')
            elseif isempty(app.AvailableAnnotationIDsListBox.Items) && ~isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No annotation ids selected!')
            elseif ~isempty(app.AvailableAnnotationIDsListBox.Items) && isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No experiment file name selected!')
            end

        end

        % Value changed function: ExperimentFileNameEditField
        function ExperimentFileNameEditFieldValueChanged(app, event)
            value = app.ExperimentFileNameEditField.Value;

            if isnumeric(value)
                msgbox('Experiment names cannot be numbers')
                app.ExperimentFileNameEditField.Value = '';
            else
                
                app.Experiment_File = value;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Returning to original labels extracted from xml files
            for i = 1:length(fieldnames(app.Structure_Names))
                if i == 1
                    og_structure_names = app.Structure_Names.(strcat('Layer_',num2str(i)));
                else
                    og_structure_names = [og_structure_names;app.Structure_Names.(strcat('Layer_',num2str(i)))];
                end
            end
            app.AvailableAnnotationIDsListBox.Items = og_structure_names;
            app.CombinewithotherstructureDropDown.Items = og_structure_names;

            display_value = app.AvailableAnnotationIDsListBox.Items{1};
            display_value = strsplit(display_value,'_');
            display_value = display_value{1};
            app.StructureNameEditField.Value = display_value; 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StructureNamesUIFigure = uifigure('Visible', 'off');
            app.StructureNamesUIFigure.Name = 'Structure Names';
            app.StructureNamesUIFigure.Icon = 'Logo.png';
            app.ExtractingStructurefromWSILabel = uilabel(app.StructureNamesUIFigure);
            app.ExtractingStructurefromWSILabel.Text = 'Extracting Structure from WSI';
            app.AvailableAnnotationIDsListBoxLabel = uilabel(app.StructureNamesUIFigure);
            app.AvailableAnnotationIDsListBoxLabel.Text = 'Available Annotation IDs';
            app.AvailableAnnotationIDsListBox = uilistbox(app.StructureNamesUIFigure);
            app.AvailableAnnotationIDsListBox.Items = {};
            app.AvailableAnnotationIDsListBox.Multiselect = 'on';
            app.AvailableAnnotationIDsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableAnnotationIDsListBoxValueChanged, true);
            app.AvailableAnnotationIDsListBox.Value = {};
            app.DoneButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Label = uilabel(app.StructureNamesUIFigure);
            app.Label.Text = 'Listbox below indicates annotation IDs in the slides specified by the Slide Path.  Select annotation IDs below and either re-name to the desired structure name or click "Ignore this Structure" to remove from analysis.  Alternatively, you can combine that structure with another structure.';
            app.StructureNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.StructureNameEditFieldLabel.Text = 'Structure Name';
            app.StructureNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.StructureNameEditField.ValueChangedFcn = createCallbackFcn(app, @StructureNameEditFieldValueChanged, true);
            app.IgnorethisStructureButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.IgnorethisStructureButton.ButtonPushedFcn = createCallbackFcn(app, @IgnorethisStructureButtonPushed, true);
            app.IgnorethisStructureButton.Text = 'Ignore this Structure';
            app.CombinewithotherstructureDropDownLabel = uilabel(app.StructureNamesUIFigure);
            app.CombinewithotherstructureDropDownLabel.Text = 'Combine with other structure';
            app.CombinewithotherstructureDropDown = uidropdown(app.StructureNamesUIFigure);
            app.CombinewithotherstructureDropDown.Items = {};
            app.CombinewithotherstructureDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinewithotherstructureDropDownValueChanged, true);
            app.CombinewithotherstructureDropDown.Placeholder = 'Select One';
            app.CombinewithotherstructureDropDown.Value = {};
            app.ExperimentFileNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.ExperimentFileNameEditFieldLabel.Text = 'Experiment File Name';
            app.ExperimentFileNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.ExperimentFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentFileNameEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.StructureNamesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_Extract(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StructureNamesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StructureNamesUIFigure)
        end
    end
end