classdef HistoLens_FeatureExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_FeatureExtractionUIFigure  matlab.ui.Figure
        GoButton                       matlab.ui.control.Button
        ProceedtoFeatureRankingButton  matlab.ui.control.Button
        GoBacktoFileInputWindowButton  matlab.ui.control.Button
        FeatureSetFilenameLabel        matlab.ui.control.Label
        SelectFeaturestoExtractLabel   matlab.ui.control.Label
        FeatureExtractionLabel         matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_app
        
    end
    
    properties (Access = public)
        Slide_Names
        Structure_Names
        Slide_Path
        FeatSet_File
        MPP
        Experiment_File
        Full_Feature_set
        all_feature_defs
        
        Structure_Tab
        Structure_Feature_Tree
        Structure_Include_List
        Structure_Continue_Butt
        Structure_Feature_Check
        Structure_Checkbox
        Seg_Params
        Slide_NormVals
        
    end
    
    methods (Access = public)
        
        function Structure_Feats(app,struct_name)
            
            % If one of the structures is selected then show the features that are
            % specific to that structure and the non-structure specific ones.
            % Otherwise just show the non-structure specific ones
            
            app.Structure_Feature_Tree.(struct_name) = uitree(app.Structure_Tab.(struct_name),'checkbox','Position',[6,7,329,81],'CheckedNodesChangedFcn',@(varargin)Update_Feature_Check(app));
            struct_include = app.Structure_Include_List.(struct_name);
            
            reduced_feature_defs = app.all_feature_defs(ismember(app.all_feature_defs.Structure_Specificity,struct_include),:);
            compartments = unique(reduced_feature_defs.Compartment);
            
            % Adding feature types to each compartment
            for i = 1:length(compartments)
                parent = uitreenode(app.Structure_Feature_Tree.(struct_name),'Text',compartments{i});
                
                % Feature types included with this given compartment
                compartment_feature_types = reduced_feature_defs(strcmp(reduced_feature_defs.Compartment,compartments{i}),:);
                
                types = unique(compartment_feature_types.Type);
                for j = 1:length(types)
                    child = uitreenode(parent,'Text',types{j});
                    
                    types_specific_features = compartment_feature_types(strcmp(compartment_feature_types.Type,types{j}),:);
                    
                    specs = unique(types_specific_features.Feature_Names);
                    for k = 1:length(specs)
                        grandchild = uitreenode(child,'Text',specs{k});
                    end
                    
                end
            end
            
            app.Structure_Continue_Butt.(struct_name).Enable = 'off';
            
        end
        
        function CheckBox_Change(app,struct_name,cbx)
            % Adding or removing a structure-specificity from the structure
            % include list for that specific structure
            val = cbx.Value;
            
            if strcmp(cbx.Text,'Neither')
                add_text = 'Non_Structure_Specific';
            end
            if strcmp(cbx.Text,'Glomeruli')
                add_text = 'Glom_Specific';
            end
            if strcmp(cbx.Text,'Tubules')
                add_text = 'Tubule_Specific';
            end
            
            prev_list = app.Structure_Include_List.(struct_name);
            if val
                if ~isempty(prev_list)
                    app.Structure_Include_List.(struct_name) = [prev_list,add_text];
                else
                    app.Structure_Include_List.(struct_name) = {add_text};
                end
            else
                rem_idx = find(strcmp(prev_list,add_text));
                prev_list(rem_idx) = [];
                app.Structure_Include_List.(struct_name) = prev_list;
                
            end
            
        end
        
        function Update_Feature_Check(app)
            
            % Verifying that there are features selected for all structures

            for st = 1:length(fieldnames(app.Structure_Feature_Tree))
                structure_name = fieldnames(app.Structure_Feature_Tree);
                structure_name = structure_name{st};
                
                if length(app.Structure_Feature_Tree.(structure_name).CheckedNodes) > 0
                    app.Structure_Feature_Check(st) = true;
                end
            
            end
            
            if all(app.Structure_Feature_Check)
                app.GoButton.Enable = 'on';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Names = main_app.Slide_Names;
            app.Slide_Path = main_app.Slide_Path;
            app.Structure_Names = main_app.Structure_Names;
            app.Slide_Names = main_app.Slide_Names;
            app.Seg_Params = main_app.Seg_Params;
            app.Experiment_File = main_app.Experiment_File;

            app.all_feature_defs = readtable('Master_Feature_Categories.csv');
            
            % Creating tab group with new tab for each structure
            features_tab_group = uitabgroup(app.HistoLens_FeatureExtractionUIFigure,'Position',[11,301,346,188]);
            structures = fieldnames(app.Structure_Names);
            for st = 1:length(structures)
                structure_name = structures{st};
                app.Structure_Tab.(structure_name) = uitab(features_tab_group,'Title',structure_name,'Scrollable','on');
                
                % Changing to check-boxes and making the feature type tree
                % afterwards
                app.Structure_Checkbox.(structure_name).cbx1 = uicheckbox(app.Structure_Tab.(structure_name), 'Text','Neither','Position',[11,132,61,22],'ValueChangedFcn',@(cbx1,event)CheckBox_Change(app,structure_name,cbx1));
                app.Structure_Checkbox.(structure_name).cbx2 = uicheckbox(app.Structure_Tab.(structure_name), 'Text','Glomeruli','Position',[87,132,73,22],'ValueChangedFcn',@(cbx2,event)CheckBox_Change(app,structure_name,cbx2));
                app.Structure_Checkbox.(structure_name).cbx3 = uicheckbox(app.Structure_Tab.(structure_name), 'Text','Tubules','Position',[175,132,65,22],'ValueChangedFcn',@(cbx3,event)CheckBox_Change(app,structure_name,cbx3));
                
                app.Structure_Include_List.(structure_name) = [];
                app.Structure_Continue_Butt.(structure_name) = uibutton(app.Structure_Tab.(structure_name),'Text','Continue','Position',[6,94,154,22],'ButtonPushedFcn',@(varargin)Structure_Feats(app,structure_name));
            end
                        
        end

        % Button pushed function: GoBacktoFileInputWindowButton
        function GoBacktoFileInputWindowButtonPushed(app, event)
            
            delete(app)
            
        end

        % Button pushed function: ProceedtoFeatureRankingButton
        function ProceedtoFeatureRankingButtonPushed(app, event)
            
            % Go to Feature Ranking window
            main_app = app.main_app;
            main_app.Full_Feature_set = app.Full_Feature_set;
            delete(app)
            main_app.HistoLens_FeatureRanking = HistoLens_FeatureRanking(main_app);
            
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            % Turning off functionality of everything else in this window
            %app.GoButton.Enable = 'off';
            
            structures = fieldnames(app.Structure_Names);
            for st = 1:length(structures)
                struct_name = structures{st};
                app.Structure_Feature_Tree.(struct_name).Enable = 'off';
                
                app.Structure_Checkbox.(struct_name).cbx1.Enable = 'off';
                app.Structure_Checkbox.(struct_name).cbx2.Enable = 'off';
                app.Structure_Checkbox.(struct_name).cbx3.Enable = 'off';

            end
            
            % Defining structure_name from app.main_app.Structure
            structures = fieldnames(app.Structure_Names);
            for st = 1:length(structures)
                structure_name = structures{st};
                
                % Get Features that are going to be extracted
                checked_nodes = app.Structure_Feature_Tree.(structure_name).CheckedNodes;
                checked_text = [];
                
                for i = 1:length(checked_nodes)
                    if isempty(checked_nodes(i).Children)
                        if isempty(checked_text)
                            checked_text = {checked_nodes(i).Text};
                        else
                            checked_text = [checked_text,checked_nodes(i).Text];
                        end
                    end
                end
                
                % checked_text only has the specific feature names
                % Getting the feature names from the feature categories
                %subset_table = app.all_feature_defs(ismember(app.all_feature_defs.Feature_Names,checked_text)&ismember(app.all_feature_defs.Structure_Specificity,app.Structure_Include_List.(structure_name)),:)
                feature_indices = ismember(app.all_feature_defs.Feature_Names,checked_text)&ismember(app.all_feature_defs.Structure_Specificity,app.Structure_Include_List.(structure_name));
                feature_indices = find(feature_indices);
                % Constructing the feature set filename
                app.FeatSet_File = strcat(app.Slide_Path,filesep,structure_name,'_Features.csv');
            
                % Run Catch_N_Release feature extraction
                Catch_N_Release(app,structure_name,feature_indices)
                cla(app.UIAxes)
                
                % Function to process generated outputs (compartment
                % segmentation script, feature set) for later use without
                % new compartment segmentation and feature extraction
                % dealing with slides
                %Format_Experiment_File(app,app.main_app)
                
            end
            
            app.ProceedtoFeatureRankingButton.Enable = 'on';
            app.GoBacktoFileInputWindowButton.Enable = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_FeatureExtractionUIFigure = uifigure('Visible', 'off');
            app.HistoLens_FeatureExtractionUIFigure.Name = 'HistoLens_FeatureExtraction';
            app.HistoLens_FeatureExtractionUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.HistoLens_FeatureExtractionUIFigure);
            title(app.UIAxes, 'On Slide: Progress:')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UITable = uitable(app.HistoLens_FeatureExtractionUIFigure);
            app.UITable.ColumnName = {'Slide Name'; 'Structure Number'};
            app.UITable.RowName = {};
            app.FeatureExtractionLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.FeatureExtractionLabel.Text = 'Feature Extraction ';
            app.SelectFeaturestoExtractLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.SelectFeaturestoExtractLabel.Text = 'Select whether to use structure-specific features, followed by feature types:';
            app.FeatureSetFilenameLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.FeatureSetFilenameLabel.Text = 'Feature Set Filename: ';
            app.GoBacktoFileInputWindowButton = uibutton(app.HistoLens_FeatureExtractionUIFigure, 'push');
            app.GoBacktoFileInputWindowButton.ButtonPushedFcn = createCallbackFcn(app, @GoBacktoFileInputWindowButtonPushed, true);
            app.GoBacktoFileInputWindowButton.Enable = 'off';
            app.GoBacktoFileInputWindowButton.Text = 'Go Back to File Input Window';
            app.ProceedtoFeatureRankingButton = uibutton(app.HistoLens_FeatureExtractionUIFigure, 'push');
            app.ProceedtoFeatureRankingButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoFeatureRankingButtonPushed, true);
            app.ProceedtoFeatureRankingButton.Enable = 'off';
            app.ProceedtoFeatureRankingButton.Text = 'Proceed to Feature Ranking';
            app.GoButton = uibutton(app.HistoLens_FeatureExtractionUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.HistoLens_FeatureExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_FeatureExtraction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_FeatureExtractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_FeatureExtractionUIFigure)
        end
    end
end