classdef HistoLens_File_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_FilesUIFigure        matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        SelectFolderofSlidesButton     matlab.ui.control.Button
        SelectTypeofInputButtonGroup   matlab.ui.container.ButtonGroup
        InfoSym_Notes                  matlab.ui.control.Image
        HistoLensExperimentFileButton  matlab.ui.control.ToggleButton
        WSIsandAnnotationFilesButton   matlab.ui.control.ToggleButton
        DoneButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_app % Storing initial app
        msg_output % Warning output after pushing Done Button
        wait_for_structure = false % Used to read in image paths with specified structure
        multi_slide = false % After structure whether or not there are multiple slides in the directory
    end
    
    properties (Access = public)
        base_Feature_set % un-editable feature set
        
        HistoLens_CompartmentSegmentation
        HistoLens_Extract
        HistoLens_FeatureExtraction
        HistoLens_FeatureRanking
        
        Structure_Names
        Input_Type = 'NewExtract';
        Experiment_File
        Experiment_Struct
        Aligned_Labels
       
        Full_Feature_set % input features set
        Slide_Path % base directory of raw data
        Slide_Names
        Slide_Num % Number of slides
        Structure % Structure within dataset
        Feat_Rank % Rank of each feature
        Feat_Set_File % Filename of feature set
        Feat_Rank_File % Filename of feature rankings
        Notes_File % Filename of Notes file
        Notes % Notes table for images in dataset
        MPP % Microns per Pixel value
        structure_info
        structure_idx
        Seg_Params


        % Adding parameters for AdvancedCompartmentSegmentation and
        % Multi-Stain Registration (not implemented)
        % Stain_Table
        % Registration_Transforms
        
    end
    
    methods (Access = public)
        
        function Get_Structure_Names(app,dlg_app)
            
            structure_list = dlg_app.AvailableAnnotationIDsListBox.Items;
            structure_names = cellfun(@(x)strsplit(x,'_'),structure_list,'UniformOutput',false);
            
            % Comma separated list if multiple are grouped under a single
            % label
            annotation_ids = cellfun(@(x)x{end},structure_names,'UniformOutput',false);
            annotation_ids = cellfun(@(x)strsplit(x,','),annotation_ids,'UniformOutput',false);
            structure_names = cellfun(@(x)x{1},structure_names,'UniformOutput',false);
            
            id_count = 1;
            for i = structure_names
                ids = annotation_ids(id_count);
                if iscell(ids) && ischar(ids{1})
                    app.Structure_Names.(i{1}).Annotation_ID = str2double(ids);
                    id_count = id_count+1;
                else
                    app.Structure_Names.(i{1}).Annotation_ID = str2double(ids{1});
                    id_count = id_count+1;
                end

            end            
            app.Experiment_File = dlg_app.Experiment_File;

            delete(dlg_app)
            
        end
        
        function Get_Seg_Params(app,dlg_app)
            app.Seg_Params = dlg_app.Seg_Params;
            
        end

        function Get_Experiment_Struct(app,dlg_app)
            app.Experiment_Struct = dlg_app.Experiment_Struct;
        end
        
        
        function Default_Slide_Labels(app)
            % Using the slide-names property of 'app' to generate an
            % initial table of slide-level labels consisting of just the
            % slide name that each structure is derived from
            structures = fieldnames(app.Structure_Names);
            for st = 1:length(structures)
                current_structure = structures{st};

                % Generating initial feature rankings using slide labels
                % for each structure
                all_img_labels = app.Full_Feature_set.(current_structure).ImgLabel;
                all_img_slide_labels = cellfun(@(x) strsplit(x,'_'),all_img_labels,'UniformOutput',false);
                
                lengths = cellfun('length',all_img_slide_labels);
                if any(lengths>2)
                    all_img_slide_labels = cellfun(@(x) strjoin(x(1:end-1),'_'),all_img_slide_labels,'UniformOutput',false);
                else
                    all_img_slide_labels = cellfun(@(x) x{1},all_img_slide_labels,'UniformOutput',false);
                end
                    
                all_img_slide_labels = cellstr(all_img_slide_labels);

                slide_slide_labels = cell2table([all_img_labels,all_img_slide_labels],'VariableNames',{'ImgLabel','Class'});
                app.Aligned_Labels.(current_structure).Label_1.Aligned = slide_slide_labels;
                app.Aligned_Labels.(current_structure).Label_1.Name = 'Slide Names';
                app.Aligned_Labels.(current_structure).Label_1.Sub_Class = unique(slide_slide_labels.Class);
                app.Aligned_Labels.(current_structure).AllLabels = {'Slide Names'};
                
                % Feature ranking one-vs-all for each slide label
                feature_ranking_features = app.Full_Feature_set.(current_structure)(:,1:end-1);
                slides = unique(slide_slide_labels.Class);
                rankings_table = table('RowNames',app.Full_Feature_set.(current_structure).Properties.VariableNames(1:end-1));
                for s = 1:length(slides)
                    this_slide = slides{s};
                    % Re-naming all except the current slide 'Not'
                    ranking_labels = slide_slide_labels.Class;
                    ranking_labels(find(~strcmp(ranking_labels,this_slide))) = {'Not'};
                    
                    ranking_labels = cell2table(ranking_labels,'VariableNames',{'Class'});
                    % Starting with chi-square ranks by default but this
                    % can be modified later in the main window as well as
                    % the scaling (whether to use raw rank, inverse rank,
                    % or value)
                    feature_ranks = fscchi2([feature_ranking_features,ranking_labels],'Class');
                    [~,ordered_ranks] = intersect(feature_ranks,(1:width(feature_ranking_features)));
                    
                    % Make one table with a column for each sub-label and a
                    % row for each feature
                    sub_label = strcat('SubClass_',num2str(s));
                    cat_table = table(ordered_ranks,'VariableNames',{sub_label});

                    rankings_table = [rankings_table,cat_table];

                end
                app.Feat_Rank.(current_structure).Table = rankings_table;
                % Recording both the method for determining the feature
                % rankings and what form the scores are taking
                app.Feat_Rank.(current_structure).Method = 'chi-square';
                app.Feat_Rank.(current_structure).Scores = 'Linear Rank';
                app.Feat_Rank.(current_structure).AllRanks = slides;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Experiment_File = main_app.Experiment_File;
            
        end

        % Close request function: HistoLens_FilesUIFigure
        function HistoLens_FilesUIFigureCloseRequest(app, event)
            
            try
                app.main_app.InputFilesButton.Enable = 'on';
            end
            delete(app.HistoLens_CompartmentSegmentation)
            delete(app.HistoLens_FeatureExtraction)
            delete(app.HistoLens_FeatureRanking)
            delete(app)
            
        end

        % Button pushed function: SelectFolderofSlidesButton
        function SelectFolderofSlidesButtonPushed(app, event)
            
            app.SelectFolderofSlidesButton.Enable = 'off';
            
            % Dependent on type of input. (app.Input_Type)
            if strcmp(app.Input_Type,'FromExpFile')
                [filename,path] = uigetfile('*');
                
                if ~filename==0
                    app.Experiment_File = strcat(path,filesep,filename);
                    
                    % Outputs structure info and app.Slide_Path
                    app.structure_info = Load_Experiment(app);

                    dir_contents = dir(app.Slide_Path);
                    dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
                    app.Slide_Num = length(dir_contents(contains({dir_contents.name},{'.svs'})));
                        
                    structures = fieldnames(app.structure_info);
                    structures = structures(~ismember(structures,'StainNormalization'));

                    str_annotation_ids = {};
                    str_structure_num = {};
                    for s = 1:length(structures)
                        current_structure = structures{s};
                        app.structure_idx.(current_structure) = app.structure_info.(current_structure).AnnotationID;
                        app.Full_Feature_set.(current_structure) = app.structure_info.(current_structure).FeatureSet;

                        % Getting aligned labels and feature ranks from
                        % structure info
                        app.Aligned_Labels.(current_structure) = app.structure_info.(current_structure).Aligned_Labels;
                        app.Feat_Rank.(current_structure) = app.structure_info.(current_structure).Feat_Rank;

                        % Making string dataset info for populating table
                        str_ann = strrep(num2str(app.structure_idx.(current_structure)),'  ',' and ');
                        str_annotation_ids = [str_annotation_ids,{str_ann}];
                        str_n_struct = num2str(height(app.Full_Feature_set.(current_structure)));
                        str_structure_num = [str_structure_num,{str_n_struct}];
                        
                    end

                    app.DoneButton.Enable = 'on';

                else
                    app.SelectFolderofSlidesButton.Enable = 'on';
                end


            end

            if strcmp(app.Input_Type,'NewExtract')
                
                app.Slide_Path = uigetdir('Select Folder containing single or multiple slides:');  
                if ~app.Slide_Path==0
                
                    %app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Enable = 'off';
                    app.SelectTypeofInputButtonGroup.Enable = 'off';
                    
                    % Defining annotation IDs within WSIs, including,
                    % excluding, and combining annotation IDs into specific
                    % named structures
                    app.HistoLens_Extract = HistoLens_Extract(app);
                    waitfor(app.HistoLens_Extract)
                    
                    % Constructing the compartment segmentation script
                    app.HistoLens_CompartmentSegmentation = HistoLens_CompartmentSegmentation(app);
                    waitfor(app.HistoLens_CompartmentSegmentation)
                    
                    slide_names = dir(app.Slide_Path);
                    slide_names = {slide_names.name};
                    slide_names = slide_names(~ismember(slide_names,{'.','..'}));
                    slide_names = slide_names(contains(slide_names,{'.svs','.ndpi'}));
                    app.Slide_Names = slide_names;
                    app.Slide_Num = length(app.Slide_Names);
                                        
                    % Extracting features from slides using
                    % Catch_N_Release. Currently implementing single
                    % structure (glom) feature extraction and combination
                    app.HistoLens_FeatureExtraction = HistoLens_FeatureExtraction(app);
                    waitfor(app.HistoLens_FeatureExtraction)
                    app.DoneButton.Enable = 'on';
                    
                    app.Experiment_File = strcat(app.Slide_Path,filesep,app.Experiment_File,'.xml');
                    app.structure_info = Load_Experiment(app);
                    
                    structures = fieldnames(app.structure_info);
                    structures = structures(~ismember(structures,'StainNormalization'));
                    str_annotation_ids = {};
                    str_structure_num = {};
                    for s = 1:length(structures)
                        current_structure = structures{s};
                        app.structure_idx.(current_structure) = app.structure_info.(current_structure).AnnotationID;
                        app.Full_Feature_set.(current_structure) = app.structure_info.(current_structure).FeatureSet;

                        % Making string dataset info for populating table
                        str_ann = strrep(num2str(app.structure_idx.(current_structure)),'  ',' and ');
                        str_annotation_ids = [str_annotation_ids,{str_ann}];
                        str_n_struct = num2str(height(app.Full_Feature_set.(current_structure)));
                        str_structure_num = [str_structure_num,{str_n_struct}];
                        
                    end

                    app.DoneButton.Enable = 'on';

                    % Load default slide-level labels (Creating
                    % app.Aligned_Labels)
                    Default_Slide_Labels(app)

                    % Write these default labels to experiment file
                    for s = 1:length(structures)
                        structure_name = structures{s};
                        app.Experiment_Struct.Structure.(structure_name).Aligned_Labels.Label_1.Aligned = Special_Table2Struct(app.Aligned_Labels.(structure_name).Label_1.Aligned);
                        app.Experiment_Struct.Structure.(structure_name).Aligned_Labels.Label_1.Sub_Class = app.Aligned_Labels.(structure_name).Label_1.Sub_Class;
                        app.Experiment_Struct.Structure.(structure_name).Aligned_Labels.Label_1.Name = app.Aligned_Labels.(structure_name).Label_1.Name;
                        app.Experiment_Struct.Structure.(structure_name).Aligned_Labels.AllLabels = app.Aligned_Labels.(structure_name).AllLabels;
                        
                        % Default feature rankings
                        app.Experiment_Struct.Structure.(structure_name).Feat_Rank.Table = Special_Table2Struct(app.Feat_Rank.(structure_name).Table);
                        app.Experiment_Struct.Structure.(structure_name).Feat_Rank.Method = app.Feat_Rank.(structure_name).Method;
                        app.Experiment_Struct.Structure.(structure_name).Feat_Rank.Scores = app.Feat_Rank.(structure_name).Scores;
                        app.Experiment_Struct.Structure.(structure_name).Feat_Rank.AllRanks = app.Feat_Rank.(structure_name).AllRanks;
                    end

                    writestruct(app.Experiment_Struct,app.Experiment_File,'StructNodeName','HistoLensExperimentParameters')
                else
                    app.SelectFolderofSlidesButton.Enable = 'on';
                end

            end

            if strcmp(app.SelectFolderofSlidesButton.Enable,'off')
                    % Making table visible and populating with experiment
                    % information
                    % Order is:slide path, structure names, annotation ids,
                    % number of structures in each structure, and label ids
                    % (which for default is just slide names)
                    assignin('base','app',app)
                    app.UITable.Visible = 'on';

                    app.UITable.Data = {char(app.Slide_Path); char(strjoin(fieldnames(app.Full_Feature_set),','));...
                        char(strjoin(str_annotation_ids,',')); char(strjoin(str_structure_num,','));...
                        'Slide Names'};
                    app.UITable.RowName = {'Slide Path','Structure Names','Annotation IDs','Number of Structures','Labels'};
    
            end


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
                
            app.base_Feature_set = app.Full_Feature_set;
            Process_Inputs(app.main_app, app, event)
            app.main_app.InputFilesButton.Enable = 'on';
            
            delete(app)
                       
        end

        % Image clicked function: InfoSym_Notes
        function InfoSym_NotesImageClicked(app, event)
            
            % Information symbol for file inputs
            msgbox({{'Click WSIs and Annotation files if you are starting a new HistoLens Experiment'};...
                {'If you have already processed this dataset in HistoLens, you should have an experiment file (.xml) and you can select HistoLens Experiment File'}})

        end

        % Selection changed function: SelectTypeofInputButtonGroup
        function SelectTypeofInputButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectTypeofInputButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'WSIs and Annotation Files')
                app.Input_Type = 'NewExtract';
                app.SelectFolderofSlidesButton.Text = 'Select Folder of Slide(s)';
            else
                app.Input_Type = 'FromExpFile';
                app.SelectFolderofSlidesButton.Text = 'Select Experiment File';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_FilesUIFigure = uifigure('Visible', 'off');
            app.HistoLens_FilesUIFigure.Name = 'HistoLens_Files';
            app.HistoLens_FilesUIFigure.Icon = 'Logo.png';
            app.HistoLens_FilesUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistoLens_FilesUIFigureCloseRequest, true);
            app.HistoLens_FilesUIFigure.Tag = 'File_Input_Dialog';
            app.DoneButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tag = 'Done_Butt';
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.SelectTypeofInputButtonGroup = uibuttongroup(app.HistoLens_FilesUIFigure);
            app.SelectTypeofInputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTypeofInputButtonGroupSelectionChanged, true);
            app.SelectTypeofInputButtonGroup.Title = 'Select Type of Input';
            app.WSIsandAnnotationFilesButton = uitogglebutton(app.SelectTypeofInputButtonGroup);
            app.WSIsandAnnotationFilesButton.Text = 'WSIs and Annotation Files';
            app.WSIsandAnnotationFilesButton.Value = true;
            app.HistoLensExperimentFileButton = uitogglebutton(app.SelectTypeofInputButtonGroup);
            app.HistoLensExperimentFileButton.Text = 'HistoLens Experiment File';
            app.InfoSym_Notes = uiimage(app.SelectTypeofInputButtonGroup);
            app.InfoSym_Notes.ImageClickedFcn = createCallbackFcn(app, @InfoSym_NotesImageClicked, true);
            app.InfoSym_Notes.ImageSource = 'info_symbol.png';
            app.SelectFolderofSlidesButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.SelectFolderofSlidesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderofSlidesButtonPushed, true);
            app.SelectFolderofSlidesButton.Text = 'Select Folder of Slide(s)';
            app.UITable = uitable(app.HistoLens_FilesUIFigure);
            app.UITable.ColumnName = {'Provided Values'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            % Show the figure after all components are created
            app.HistoLens_FilesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_File_Input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_FilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_FilesUIFigure)
        end
    end
end